classdef TTP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ComponentmanagerTab             matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ManualTab                       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        RequiredTab                     matlab.ui.container.Tab
        ClearTableButton                matlab.ui.control.Button
        CreateUpdatetableButton         matlab.ui.control.Button
        AddUpdateDataButton             matlab.ui.control.Button
        DataEntryTextArea               matlab.ui.control.TextArea
        DataEntryTextAreaLabel          matlab.ui.control.Label
        TableVariableButtonGroup        matlab.ui.container.ButtonGroup
        CASButton                       matlab.ui.control.ToggleButton
        ComponentButton                 matlab.ui.control.ToggleButton
        cp_J_per_K_molButton            matlab.ui.control.ToggleButton
        cp_temperature_KButton          matlab.ui.control.ToggleButton
        enthalpy_vaporization_kJ_per_molButton  matlab.ui.control.ToggleButton
        enthalpy_vaporization_temperature_KButton  matlab.ui.control.ToggleButton
        saturation_pressure_kPaButton   matlab.ui.control.ToggleButton
        saturation_temperature_KButton  matlab.ui.control.ToggleButton
        criticalPressure_kPaButton      matlab.ui.control.ToggleButton
        criticalTemperature_KButton     matlab.ui.control.ToggleButton
        AllinthissectionisusedforLabel  matlab.ui.control.Label
        CASisusedAsauniqueidentifiertolinkdatafromLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        FileTab                         matlab.ui.container.Tab
        Tab_2                           matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateUpdatetableButton
        function CreateUpdatetableButtonPushed(app, event)
                % Get the user's "Documents" folder path
            userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            if ~ispc
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            
            % Define the main data folder and the experimental subfolder
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
            experimentalFolder = fullfile(dataFolder, 'Experimental_Data');
            
            % Create folders if they don't exist
            if ~exist(dataFolder, 'dir')
                mkdir(dataFolder);
            end
            if ~exist(experimentalFolder, 'dir')
                mkdir(experimentalFolder);
            end
            
            % Extract the Component and CAS Number from the first row of the table
            tableData = app.UITable.Data; % Extract the data from the table
            if isempty(tableData) || size(tableData, 1) < 1 || size(tableData, 2) < 2
                uialert(app.UIFigure, 'Table must have at least one row with Component and CAS data.', 'Save Error');
                return;
            end
            
            componentName = tableData{1, 1}; % First row, first column
            casNumber = tableData{1, 2}; % First row, second column
            
            % Validate that both Component and CAS are non-empty strings
            if isempty(componentName) || isempty(casNumber) || ~ischar(componentName) || ~ischar(casNumber)
                uialert(app.UIFigure, 'Component name and CAS number must be non-empty strings in the first row.', 'Save Error');
                return;
            end
            
            % Remove special characters from the Component Name
            modifiedComponentName = regexprep(componentName, '[^a-zA-Z0-9]', '');
            
            % Construct the filename using the modified component name and CAS number
            dataFileName = sprintf('%s_%s.csv', modifiedComponentName, casNumber);
            
            % Define the full path for the .csv file
            dataFilePath = fullfile(experimentalFolder, dataFileName);
            
            % Convert cell array data to a table for easy writing to CSV
            headers = app.UITable.ColumnName; % Get the column headers
            tableDataCell = app.UITable.Data; % Get the cell array data
            dataTable = cell2table(tableDataCell, 'VariableNames', headers);
            
            % Save the table data to a CSV file
            try
                writetable(dataTable, dataFilePath);
                % Inform the user that data has been saved successfully
                uialert(app.UIFigure, sprintf('Data has been saved successfully as "%s".', dataFileName), 'Save Successful');
            catch ME
                % Inform the user if there was an error saving the data
                uialert(app.UIFigure, sprintf('Failed to save data: %s', ME.message), 'Save Error');
            end
        end

        % Button pushed function: AddUpdateDataButton
        function AddUpdateDataButtonPushed(app, event)
             % Get the data entered by the user
            userInput = app.DataEntryTextArea.Value; % Get input as a string
        
            % Split the user input based on delimiters (commas, new lines, spaces, tabs)
            rows = split(userInput, '\n'); % Split input into rows based on new lines
        
            % Determine if the input has multiple rows and columns
            numRows = length(rows);
            numCols = cellfun(@(x) length(split(x, {',', '\t', ' '})), rows);
        
            % If the number of columns is inconsistent or more than one column and one row, show an error
            if numRows > 1 && any(numCols > 1)
                uialert(app.UIFigure, 'Invalid input format. Please enter either a row or a column vector. Matrix input is not accepted.', 'Input Error');
                return;
            end
        
            % Flatten the input into a single array of entries
            dataEntries = split(userInput, {',', '\n', '\t', ' '});
            dataEntries = strtrim(dataEntries); % Trim any extra whitespace from each entry
            dataEntries(cellfun('isempty', dataEntries)) = []; % Remove empty cells
        
            % Determine if the data is intended to be a number or a string
            selectedButton = app.TableVariableButtonGroup.SelectedObject;
        
            % Determine which column to add the data to based on the selected button
            if selectedButton == app.ComponentButton
                colIdx = 1;
                isNumericData = false; % Component name is expected as a string
            elseif selectedButton == app.CASButton
                colIdx = 2;
                isNumericData = false; % CAS is expected as a string (it can be formatted like "50-00-0")
            elseif selectedButton == app.criticalTemperature_KButton
                colIdx = 3;
                isNumericData = true;
            elseif selectedButton == app.criticalPressure_kPaButton
                colIdx = 4;
                isNumericData = true;
            elseif selectedButton == app.saturation_temperature_KButton
                colIdx = 5;
                isNumericData = true;
            elseif selectedButton == app.saturation_pressure_kPaButton
                colIdx = 6;
                isNumericData = true;
            elseif selectedButton == app.enthalpy_vaporization_temperature_KButton
                colIdx = 7;
                isNumericData = true;
            elseif selectedButton == app.enthalpy_vaporization_kJ_per_molButton
                colIdx = 8;
                isNumericData = true;
            elseif selectedButton == app.cp_temperature_KButton
                colIdx = 9;
                isNumericData = true;
            elseif selectedButton == app.cp_J_per_K_molButton
                colIdx = 10;
                isNumericData = true;
            else
                uialert(app.UIFigure, 'Please select a variable to enter data.', 'Selection Error');
                return;
            end
        
            % Validate and convert data entries
            if isNumericData
                % Convert all entries to numeric values
                numericValues = str2double(dataEntries);
                if any(isnan(numericValues))
                    uialert(app.UIFigure, 'Please enter valid numeric values.', 'Input Error');
                    return;
                end
                dataToAdd = num2cell(numericValues); % Convert to cell array for table
            else
                % If the data should be strings, make sure all entries are valid strings
                dataToAdd = cellfun(@char, dataEntries, 'UniformOutput', false); % Convert to cell array of strings
            end
        
            % Prepare new table data, replacing the entire selected column with new data
            currentData = app.UITable.Data;
        
            % Ensure that currentData has the correct number of rows
            maxRows = max(length(dataToAdd), size(currentData, 1));
            if isempty(currentData)
                currentData = cell(maxRows, 10); % Initialize with empty cells to fit all columns
            elseif size(currentData, 1) < maxRows
                currentData{maxRows, 10} = []; % Expand to have enough rows
            end
        
            % Replace the selected column with new data
            for i = 1:maxRows
                if i <= length(dataToAdd)
                    currentData{i, colIdx} = dataToAdd{i};
                else
                    currentData{i, colIdx} = []; % Clear old data if the new input is shorter
                end
            end
        
            % Update the table with the new data
            app.UITable.Data = currentData;
        
            % Clear the DataEntryTextArea after entering the data
            app.DataEntryTextArea.Value = '';
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            % Set the UITable's Data property to an empty cell array to clear the table
            app.UITable.Data = {};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComponentmanagerTab = uitab(app.TabGroup);
            app.ComponentmanagerTab.Title = 'Component manager';
            app.TabGroup3 = uitabgroup(app.ComponentmanagerTab);
            app.ManualTab = uitab(app.TabGroup3);
            app.ManualTab.Title = 'Manual';
            app.TabGroup2 = uitabgroup(app.ManualTab);
            app.RequiredTab = uitab(app.TabGroup2);
            app.RequiredTab.Title = 'Required';
            app.UITable = uitable(app.RequiredTab);
            app.UITable.ColumnName = {'Component'; 'CAS'; 'criticalTemperature_K'; 'criticalPressure_kPa'; 'saturation_temperature_K'; 'saturation_pressure_kPa'; 'enthalpy_vaporization_temperature_K'; 'enthalpy_vaporization_kJ_per_mol'; 'cp_temperature_K'; 'cp_J_per_K_mol'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel = uilabel(app.RequiredTab);
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel.Text = 'CAS number is essential for linking and cross-referencing data between different tables.';
            app.AllinthissectionisusedforLabel = uilabel(app.RequiredTab);
            app.AllinthissectionisusedforLabel.Text = 'All data in this section is used for the optimization of alpha parameters, excluding data will require modification of optimization process. ';
            app.TableVariableButtonGroup = uibuttongroup(app.RequiredTab);
            app.TableVariableButtonGroup.Title = 'Table Variable';
            app.criticalTemperature_KButton = uitogglebutton(app.TableVariableButtonGroup);
            app.criticalTemperature_KButton.Text = 'criticalTemperature_K';
            app.criticalTemperature_KButton.Value = true;
            app.criticalPressure_kPaButton = uitogglebutton(app.TableVariableButtonGroup);
            app.criticalPressure_kPaButton.Text = 'criticalPressure_kPa';
            app.saturation_temperature_KButton = uitogglebutton(app.TableVariableButtonGroup);
            app.saturation_temperature_KButton.Text = 'saturation_temperature_K';
            app.saturation_pressure_kPaButton = uitogglebutton(app.TableVariableButtonGroup);
            app.saturation_pressure_kPaButton.Text = 'saturation_pressure_kPa';
            app.enthalpy_vaporization_temperature_KButton = uitogglebutton(app.TableVariableButtonGroup);
            app.enthalpy_vaporization_temperature_KButton.Text = 'enthalpy_vaporization_temperature_K';
            app.enthalpy_vaporization_kJ_per_molButton = uitogglebutton(app.TableVariableButtonGroup);
            app.enthalpy_vaporization_kJ_per_molButton.Text = 'enthalpy_vaporization_kJ_per_mol';
            app.cp_temperature_KButton = uitogglebutton(app.TableVariableButtonGroup);
            app.cp_temperature_KButton.Text = 'cp_temperature_K';
            app.cp_J_per_K_molButton = uitogglebutton(app.TableVariableButtonGroup);
            app.cp_J_per_K_molButton.Text = 'cp_J_per_K_mol';
            app.ComponentButton = uitogglebutton(app.TableVariableButtonGroup);
            app.ComponentButton.Text = 'Component';
            app.CASButton = uitogglebutton(app.TableVariableButtonGroup);
            app.CASButton.Text = 'CAS';
            app.DataEntryTextAreaLabel = uilabel(app.RequiredTab);
            app.DataEntryTextAreaLabel.Text = 'Data Entry';
            app.DataEntryTextArea = uitextarea(app.RequiredTab);
            app.AddUpdateDataButton = uibutton(app.RequiredTab, 'push');
            app.AddUpdateDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddUpdateDataButtonPushed, true);
            app.AddUpdateDataButton.Text = 'Add/Update Data';
            app.CreateUpdatetableButton = uibutton(app.RequiredTab, 'push');
            app.CreateUpdatetableButton.ButtonPushedFcn = createCallbackFcn(app, @CreateUpdatetableButtonPushed, true);
            app.CreateUpdatetableButton.Text = 'Create/Update table';
            app.ClearTableButton = uibutton(app.RequiredTab, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.FileTab = uitab(app.TabGroup3);
            app.FileTab.Title = 'File';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTP_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end